classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Lamp                           matlab.ui.control.Lamp
        Label                          matlab.ui.control.Label
        Lamp_2                         matlab.ui.control.Lamp
        phaseroflangerCheckBox         matlab.ui.control.CheckBox
        distorsinCheckBox              matlab.ui.control.CheckBox
        vibratoCheckBox                matlab.ui.control.CheckBox
        ecoCheckBox                    matlab.ui.control.CheckBox
        reverberacinCheckBox           matlab.ui.control.CheckBox
        ExportarButton                 matlab.ui.control.Button
        SalvaraudiofiltradoButton      matlab.ui.control.Button
        ReproduciraudiofiltradoButton  matlab.ui.control.Button
        TIpodefiltroDropDown           matlab.ui.control.DropDown
        TIpodefiltroDropDownLabel      matlab.ui.control.Label
        GainSlider                     matlab.ui.control.Slider
        GainSliderLabel                matlab.ui.control.Label
        NombredearchivoEditField       matlab.ui.control.EditField
        NombredearchivoEditFieldLabel  matlab.ui.control.Label
        AplicarfiltradoButton          matlab.ui.control.Button
        ReproduciraudiooriginalButton  matlab.ui.control.Button
        CargarButton                   matlab.ui.control.Button
        FrecuenciaCentralEditFieldLabel  matlab.ui.control.Label
        FrecuenciaCentralEditField     matlab.ui.control.NumericEditField
        FactordecalidadEditFieldLabel  matlab.ui.control.Label
        FactordecalidadEditField       matlab.ui.control.NumericEditField
        FrecuenciadecorteEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecorteEditField     matlab.ui.control.NumericEditField
        PlotButton                     matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CargarButton
        function Graficar(app, event)
            global file;
            file = uigetfile;
            if  file==0
                app.Label.Text = 'Archivo no seleccionado';
            else
                app.Label.Text = file;
            end
        end

        % Value changed function: TIpodefiltroDropDown
        function TIpodefiltroDropDownValueChanged(app, event)
            value = app.TIpodefiltroDropDown.Value;
            if value =='FIR'
                %progra filtro FIR
                    
            else 
         %progra filtro IIR
            end
        end

        % Button pushed function: ReproduciraudiooriginalButton
        function play_original(app, event)
            global file;
            [x, Fs] = audioread(file);
            soundsc(x, Fs);
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            global file;
            t = linspace(0,1000000000);
            [x, Fs] = audioread(file);
            plot(app.UIAxes2,x);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Lamp = uilamp(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.phaseroflangerCheckBox = uicheckbox(app.UIFigure);
            app.phaseroflangerCheckBox.Text = 'phaser o flanger';
            app.distorsinCheckBox = uicheckbox(app.UIFigure);
            app.distorsinCheckBox.Text = 'distorsión';
            app.vibratoCheckBox = uicheckbox(app.UIFigure);
            app.vibratoCheckBox.Text = 'vibrato';
            app.ecoCheckBox = uicheckbox(app.UIFigure);
            app.ecoCheckBox.Text = 'eco';
            app.reverberacinCheckBox = uicheckbox(app.UIFigure);
            app.reverberacinCheckBox.Text = 'reverberación';
            app.ExportarButton = uibutton(app.UIFigure, 'push');
            app.ExportarButton.Text = 'Exportar';
            app.SalvaraudiofiltradoButton = uibutton(app.UIFigure, 'push');
            app.SalvaraudiofiltradoButton.Text = 'Salvar audio filtrado';
            app.ReproduciraudiofiltradoButton = uibutton(app.UIFigure, 'push');
            app.ReproduciraudiofiltradoButton.Text = 'Reproducir audio filtrado';
            app.TIpodefiltroDropDown = uidropdown(app.UIFigure);
            app.TIpodefiltroDropDown.Items = {'FIR', 'IIR'};
            app.TIpodefiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @TIpodefiltroDropDownValueChanged, true);
            app.TIpodefiltroDropDown.Value = 'FIR';
            app.TIpodefiltroDropDownLabel = uilabel(app.UIFigure);
            app.TIpodefiltroDropDownLabel.Text = 'TIpo de filtro';
            app.GainSlider = uislider(app.UIFigure);
            app.GainSlider.Limits = [0 1];
            app.GainSliderLabel = uilabel(app.UIFigure);
            app.GainSliderLabel.Text = 'Gain';
            app.NombredearchivoEditField = uieditfield(app.UIFigure, 'text');
            app.NombredearchivoEditFieldLabel = uilabel(app.UIFigure);
            app.NombredearchivoEditFieldLabel.Text = 'Nombre de archivo';
            app.AplicarfiltradoButton = uibutton(app.UIFigure, 'push');
            app.AplicarfiltradoButton.Text = 'Aplicar filtrado';
            app.ReproduciraudiooriginalButton = uibutton(app.UIFigure, 'push');
            app.ReproduciraudiooriginalButton.ButtonPushedFcn = createCallbackFcn(app, @play_original, true);
            app.ReproduciraudiooriginalButton.Text = 'Reproducir audio original';
            app.CargarButton = uibutton(app.UIFigure, 'push');
            app.CargarButton.ButtonPushedFcn = createCallbackFcn(app, @Graficar, true);
            app.CargarButton.Text = 'Cargar';
            app.FrecuenciaCentralEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciaCentralEditFieldLabel.Text = 'Frecuencia Central';
            app.FrecuenciaCentralEditField = uieditfield(app.UIFigure, 'numeric');
            app.FactordecalidadEditFieldLabel = uilabel(app.UIFigure);
            app.FactordecalidadEditFieldLabel.Text = 'Factor de calidad';
            app.FactordecalidadEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciadecorteEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciadecorteEditFieldLabel.Text = 'Frecuencia de corte';
            app.FrecuenciadecorteEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Audio procesado')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Audio original')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Respuesta en frecuencia del filtro')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end